classdef interactivePlot_SectorsDeamend < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        Lamp                matlab.ui.control.Lamp
        ComapreButton       matlab.ui.control.Button
        nameEditField       matlab.ui.control.EditField
        nameEditFieldLabel  matlab.ui.control.Label
        XEditField          matlab.ui.control.NumericEditField
        XEditFieldLabel     matlab.ui.control.Label
        YEditField          matlab.ui.control.NumericEditField
        UIAxes              matlab.ui.control.UIAxes
        RightPanel          matlab.ui.container.Panel
        UIAxes2             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        df;arr; % Description
        plt;plt2; % Description
        indHome;indCommercial;indIndustrial;
        indWater; indAgri;indPa; indGen;
        years = [2017 2018 2019 2020];
        comp = false;


    end
    
    methods (Access = private)
        
        function initApp(app)
            df = readtable('df_2.csv','ReadRowNames',true,'ReadVariableNames',true);
            arr = table2array(df)
            f = app.UIFigure;
            ax = app.UIAxes;
            app.plt = bar(ax,app.years,arr,'stacked')
            legend(ax,df.Row,"Location",'eastoutside')
            %legend(ax,df.Row,"Location",'southoutside','Orientation','horizontal')

            	
            %ylim(ax,[0, max(sum(arr(:,2)))*1.4])
            app.df = df;
            app.arr = arr;
            app.indHome = arr(1,:);
            app.indCommercial = app.indHome + arr(2,:);
            app.indWater = app.indCommercial + arr(3,:);
            app.indAgri = app.indWater + arr(4,:);
            app.indPa = app.indAgri + arr(5,:);
            app.indGen = app.indPa + arr(6,:);


        end
        
        function  plotSector(app,id)
            sectorDat = app.arr(id,:);
            c = app.plt(id).FaceColor;
            ax = app.UIAxes2;
            plot(ax,app.years,sectorDat,'LineWidth',2,'Color',c);
            tmp= app.df.Row(id);
            title(ax,[tmp{1} ' Consumption By Years']);
            xticks(ax,app.years)
            %ylim(ax,[0 max(sectorDat)])
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initApp();
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)


        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
           
            xd = app.plt.XData;
            yd = app.arr

            ax = app.UIAxes;
            x = ax.CurrentPoint(1);
            y = ax.CurrentPoint(3);
            app.YEditField.Value =y;
            app.XEditField.Value =x;
            if y>0
                [I id_x] = min(abs(x-xd(:)));
                yd = yd(:,id_x)
                for i = 2:length(yd)
                    yd(i) = yd(i-1)+yd(i)
                end
                id_y = find(yd>y,1,"first")
                %[I id_y] = min(abs(y-yd));
                tmp= app.df.Row(id_y);
                try
                app.nameEditField.Value =tmp{1}
                if app.comp == true
                    hold(app.UIAxes2,'on');
                    app.plotSector(id_y)
                    title(app.UIAxes2,'Sector Consumption')
                else
                    hold(app.UIAxes2,'off');
                    app.plotSector(id_y)
                end
                
            end
            end


        end

        % Button pushed function: ComapreButton
        function ComapreButtonPushed(app, event)
            if app.comp == true
                app.comp = false
                app.Lamp.Color = 'r'
            else
                app.comp = true
                app.Lamp.Color = 'g'
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {551, 551};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {609, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Sector Consumption by Years')
            xlabel(app.UIAxes, 'Years')
            ylabel(app.UIAxes, 'Mean Consumption [TWH]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.YEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YEditField.Visible = 'off';
            app.XEditFieldLabel = uilabel(app.LeftPanel);
            app.XEditFieldLabel.Visible = 'off';
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField.Visible = 'off';
            app.nameEditFieldLabel = uilabel(app.LeftPanel);
            app.nameEditFieldLabel.Visible = 'off';
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.LeftPanel, 'text');
            app.nameEditField.Visible = 'off';
            app.ComapreButton = uibutton(app.LeftPanel, 'push');
            app.ComapreButton.ButtonPushedFcn = createCallbackFcn(app, @ComapreButtonPushed, true);
            app.ComapreButton.Tooltip = {'This button will enable the comperasion of multiple sectors.'};
            app.ComapreButton.Text = 'Comapre';
            app.Lamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Sector Consumption by Years')
            xlabel(app.UIAxes2, 'Years')
            ylabel(app.UIAxes2, 'Mean Consumption [TWH]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [2017 2018 2019 2020];
            app.UIAxes2.XTickLabel = {'2017'; '2018'; '2019'; '2020'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interactivePlot_SectorsDeamend

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end