classdef interactivePlot_SolarPanels_potential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Panel                        matlab.ui.container.Panel
        PanelEfficenyEditField       matlab.ui.control.NumericEditField
        PanelEfficenyEditFieldLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        AreaforPVEditField           matlab.ui.control.NumericEditField
        AreaforPVEditFieldLabel      matlab.ui.control.Label
        KM2Label                     matlab.ui.control.Label
        FullYearViewButton           matlab.ui.control.Button
        AreaSlider                   matlab.ui.control.Slider
        AreaSliderLabel              matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        EditFieldLabel               matlab.ui.control.Label
        PanelsDropDown               matlab.ui.control.DropDown
        PanelsDropDownLabel          matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        clearButton                  matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        df % Description
        dfMonthHour % Description
        x;y;t = datetime('now');
        plt;
        max_y;max_x;min_y;min_x; % plot bounds
        flag = false;
        plt2; % Description
        monthInd;startMonthInd;endMonthInd; % Description
        month = 0;
        dcm; % Description
        hourTicks;hourTicksValue; % Description
        ghi;
    end

    methods (Access = private)
        function loadData(app)
            %load data and rearange table
            df =readtable("df.csv");
            hour = split(string(df.hour),':'); hour =hour(:,1);
            hour_n =[];for i = 1:length(hour);hour_n = [hour_n ;str2num(hour(i))];end
            df.hour = hour_n;
            %transfer totalDeamend
            df.(4) = str2double(df{:,4});
            %coal and gas
            df.(5) = str2double(df{:,5});
            df.(6) = str2double(df{:,6});

            %create df grouped by month and hour
            [G,id_month,id_hour] = findgroups(df.month,df.hour);
            %G_month = findgroups(df.month);
            meanByMonth = splitapply(@mean,df.totalDemend,G);
            GHI = splitapply(@mean,df.GHI__WH_M_2_,G);


            % save global variable
            app.df = df;
            app.dfMonthHour = [id_month,id_hour,meanByMonth,GHI];
        end

        function  initApp(app)
            app.loadData();
            app.plotYear();
            app.addHover(app.plt);
            %uistack(ax2, 'bottom')


        end

        function  plotGHI(app)
            efficeny = app.PanelEfficenyEditField.Value/100;
            area = app.AreaforPVEditField.Value*1000;
            ghi = app.dfMonthHour(:,4)/1000;
            ghi = (efficeny*area)*ghi;
            if app.month == 0
                set(app.plt2,'YData',ghi);
                %app.addHover(app.plt2);
                

            else
                set(app.plt2,'YData',...
                    ghi(app.startMonthInd(app.month):...
                    app.endMonthInd(app.month)));
            end
            uistack(app.plt2,'top')
            app.ghi = ghi;
            app.addPrec();
            

        end

        function addPrec(app)
            if app.month == 0
                a = 1;
                b = length(app.monthInd);
            else
                a = app.month;
                b = app.month;
            end
            delete(findobj(app.UIAxes.Children, 'flat', 'type','text'));
            for i = a:b
                try
                    monthDatTotal = sum(app.dfMonthHour(app.startMonthInd:app.endMonthInd,3));
                    monthGHITotal = sum(app.ghi(app.startMonthInd(i):app.endMonthInd(i),:))
                    str =[num2str(round((monthGHITotal/monthDatTotal)*100),2) ' %'];
                    if app.month ==0
                    t = text(app.UIAxes,app.monthInd(i)-0.5,13000,str,'FontSize',14,'FontWeight','bold');
                    else
                    t = text(app.UIAxes,11.5,8000,str,'FontSize',14,'FontWeight','bold');
                    end
                    uistack(t, 'top')
                catch
                    break
                end
                text(app.UIAxes,0,13500,'Precent of Demand :','FontSize',14,'FontWeight','bold');
             end

        end


        function addHover(app,plt)
            arr = app.dfMonthHour;
            if app.month ~= 0
                arr = arr(app.startMonthInd:app.endMonthInd,:)
            end
            str = cell(1,length(arr));
            for pp = 1:length(arr)
                t = num2str(arr(pp,2)) + ":00";
                str{pp} = t;
            end

            sadf = dataTipTextRow('time =',str);
            plt.DataTipTemplate.DataTipRows(1) = sadf;
            plt.DataTipTemplate.DataTipRows(2).Label = 'Demand =';

            app.plt2.DataTipTemplate.DataTipRows(1) = sadf;
            app.plt2.DataTipTemplate.DataTipRows(2).Label = 'Production =';

            enableDefaultInteractivity(app.UIAxes);

            %

            %dcm.UpdateFcn = @app.tempFunc;

        end
        function plotMonth(app)
            month = app.month;
            cla(app.UIAxes);
            startInd = app.startMonthInd(month);
            endInd =app.endMonthInd(month);
            m =[{'January'} {'February'} {'March'} {'April'} {'May'}...
                {'July'} {'June'} {'August'} {'September'} {'October'} {'November'} {'December'}];

            %get app pointers
            arr = app.dfMonthHour(startInd:endInd,:);
            app.max_y= max(arr(:,3)); app.min_y = min(arr(:,3));
            app.min_x =0; app.max_x = length(arr);

            xlabel(app.UIAxes,'Hour ');
            xlim(app.UIAxes,[app.min_x app.max_x]);ylim(app.UIAxes,[0 app.max_y*1.1]);
            title(app.UIAxes,m(month))

            f = app.UIFigure;
            %app.UIAxes =app.UIAxes;
            %plot main yearplot
            app.plt = area(app.UIAxes,arr(:,3));

            %add Ticks
            %hourTicks = app.hourTicks(startInd:endInd,:)
            %hourTicksValue =  app.hourTicksValue(startInd:endInd,:)
            hourTicks = arr(:,2);
            hourTicksValue =  num2str(arr(:,2))+":00";

            
            xticks(app.UIAxes,1:24);
            xticklabels(app.UIAxes,hourTicksValue(:));

            % add secondary plot
            hold(app.UIAxes,'on')
            app.plt2 = area(app.UIAxes,[0]);
            hold(app.UIAxes,'off')

            addHover(app,app.plt);
            app.plotGHI();


        end


        function plotYear(app)
            %get app pointers
            app.month = 0;
            arr = app.dfMonthHour;
            app.max_y= max(arr(:,3)); app.min_y = min(arr(:,3));
            app.min_x =0; app.max_x = length(arr);

            f = app.UIFigure;
            %app.UIAxes =app.UIAxes;
            %plot main yearplot
            app.plt = area(app.UIAxes,arr(:,3));
            app.plt = app.plt;
            %create referance ind for X location at the plot
            startInd = find(arr(:,2) == 0,12);
            endInd = find(arr(:,2) == 23,12);
            midInd = find(arr(:,2) == 12,12);
            app.startMonthInd = startInd;
            app.monthInd = midInd;
            app.endMonthInd = endInd;

            %% fron pane
            ind_4 =[find(arr(:,2) == 4,12) 3+ones(12,1)]
            ind_12 = [find(arr(:,2) == 12,12) 11+ones(12,1)]
            ind_20 = [find(arr(:,2) == 20,12) 19+ones(12,1)]
            tempArr = sortrows([ind_4;ind_12;ind_20],1);
            %add Ticks

            % add Month Ticks
            m =[{'January'} {'February'} {'March'} {'April'} {'May'}...
                {'July'} {'June'} {'August'} {'September'} {'October'} {'November'} {'December'}];
            xticks(app.UIAxes,midInd(:));
            xticklabels(app.UIAxes,m(:));
            ylabel(app.UIAxes,'Demand [MWH]')
            title(app.UIAxes,'Demand And Solar Possibility')
            xlabel(app.UIAxes,'Month')
            xlim(app.UIAxes,[app.min_x app.max_x]);ylim(app.UIAxes,[0 app.max_y*1.1])

            %add Xline
            xline(app.UIAxes,endInd(:),':');
            %add grid
            app.UIAxes.set('XGrid','on')

            % add secondary plot
            hold(app.UIAxes,'on')
            app.plt2 = area(app.UIAxes,[0]);
            uistack(app.plt2, 'bottom')
            hold(app.UIAxes,'off')

            app.plotGHI();

            %{
            %add second plot for top axes
            reer_ax =app.UIAxes_2;
            reer = area(reer_ax,arr(:,3))
            reer_ax.XAxisLocation = 'top';
            xticks(reer_ax,hourTicks(:));
            xticklabels(reer_ax,hourTicksValue(:));
            xline(reer_ax,endInd(:),':');
            reer_ax.set('YColor','none')
            reer_ax.set('XGrid','on')
            %}

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initApp();
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)


        end

        % Value changed function: AreaforPVEditField, 
        % PanelEfficenyEditField
        function changeGHI(app, event)
            app.plotGHI()
            %hold(app.UIAxes,'on')
            % = area(app.UIAxes,ghi);
            %hold(app.UIAxes,'off');
        end

        % Value changed function: PanelsDropDown
        function PanelsDropDownValueChanged(app, event)
            value = app.PanelsDropDown.Value;
            pat = digitsPattern;
            onlyNumbers = extract(value, pat);
            numericValue =char(onlyNumbers);
            numericValue = [numericValue(1)  numericValue(3) '.' numericValue(2)];
            numericValue = double(str2num(numericValue));
            app.PanelEfficenyEditField.Value= numericValue;
            app.plotGHI();
        end

        % Button down function: UIAxes, UIFigure
        function UIFigureButtonDown(app, event)
            %dcm = datacursormode(app.UIFigure); 
            %dcm.Enable = 'on';
            %info = dcm.getCursorInfo;
            %disp(info)
            %app.x = info.Position(1);
            ax = app.UIAxes;
            app.x = ax.CurrentPoint(1);
            app.y = ax.CurrentPoint(3);
            app.YEditField.Value =app.y;
            app.XEditField.Value =app.x;


            if app.y < 100
                m =[{'January'} {'February'} {'March'} {'April'} {'May'}...
                {'July'} {'June'} {'August'} {'September'} {'October'} {'November'} {'December'}];
                [c index] = min(abs(app.monthInd-app.x))
                app.month = index;
                month =m(index);
                app.EditField.Value = string(month);
                app.plotMonth();
            end
        end

        % Button pushed function: FullYearViewButton
        function FullYearViewButtonPushed(app, event)
            app.initApp();
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            dt = findall(app.plt,'Type','datatip');
            if ~isempty(dt)
                delete(dt);
            end
            dt = findall(app.plt2,'Type','datatip');
            if ~isempty(dt)
                delete(dt);
            end
        end

        % Value changing function: AreaSlider
        function AreaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.AreaforPVEditField.Value = changingValue;
            app.plotGHI();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {602, 602};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {205, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.XEditFieldLabel = uilabel(app.LeftPanel);
            app.XEditFieldLabel.Visible = 'off';
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XEditField.Visible = 'off';
            app.YEditFieldLabel = uilabel(app.LeftPanel);
            app.YEditFieldLabel.Visible = 'off';
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YEditField.Visible = 'off';
            app.PanelsDropDownLabel = uilabel(app.LeftPanel);
            app.PanelsDropDownLabel.Text = 'Panels';
            app.PanelsDropDown = uidropdown(app.LeftPanel);
            app.PanelsDropDown.Items = {'None -00.0%', 'Alpha – 21.7%', 'BIC – 22.8%', 'Power XT – 20.5%', 'Tiger Pro – 20.4%', 'Alpha -  21.7%', 'Mono PERC – 17.0%', 'HJT – 21.9%', 'NeON R – 22.0%'};
            app.PanelsDropDown.ValueChangedFcn = createCallbackFcn(app, @PanelsDropDownValueChanged, true);
            app.PanelsDropDown.Value = 'None -00.0%';
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Visible = 'off';
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.EditField.Visible = 'off';
            app.AreaSliderLabel = uilabel(app.LeftPanel);
            app.AreaSliderLabel.Text = 'Area Slider';
            app.AreaSlider = uislider(app.LeftPanel);
            app.AreaSlider.Limits = [0 50];
            app.AreaSlider.ValueChangingFcn = createCallbackFcn(app, @AreaSliderValueChanging, true);
            app.FullYearViewButton = uibutton(app.LeftPanel, 'push');
            app.FullYearViewButton.ButtonPushedFcn = createCallbackFcn(app, @FullYearViewButtonPushed, true);
            app.FullYearViewButton.Tooltip = {'return to the full yeras veiew'};
            app.FullYearViewButton.Text = 'Full Year View';
            app.Panel = uipanel(app.LeftPanel);
            app.KM2Label = uilabel(app.Panel);
            app.KM2Label.Text = '[KM^2]';
            app.AreaforPVEditFieldLabel = uilabel(app.Panel);
            app.AreaforPVEditFieldLabel.Text = 'Area for PV';
            app.AreaforPVEditField = uieditfield(app.Panel, 'numeric');
            app.AreaforPVEditField.ValueChangedFcn = createCallbackFcn(app, @changeGHI, true);
            app.AreaforPVEditField.Tooltip = {'chose the area you want to install panels.'; 'you can enter a value or use the Area slider.'};
            app.Label = uilabel(app.Panel);
            app.Label.Text = '[%]';
            app.PanelEfficenyEditFieldLabel = uilabel(app.Panel);
            app.PanelEfficenyEditFieldLabel.Text = 'Panel Efficeny';
            app.PanelEfficenyEditField = uieditfield(app.Panel, 'numeric');
            app.PanelEfficenyEditField.ValueChangedFcn = createCallbackFcn(app, @changeGHI, true);
            app.PanelEfficenyEditField.Tooltip = {'chose the panel efficency you desire.'; 'we sugest choosing an exsiting and proved panel from the drop down'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.clearButton = uibutton(app.RightPanel, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interactivePlot_SolarPanels_potential

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end